classdef LOOKVIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Q_Axes                        matlab.ui.control.UIAxes
        E_Axes                        matlab.ui.control.UIAxes
        SM_Axes                       matlab.ui.control.UIAxes
        GlobalparameterfileEditField  matlab.ui.control.EditField
        SoilparameterfileEditField    matlab.ui.control.EditField
        VegetationlibraryEditField    matlab.ui.control.EditField
        StartYearEditFieldLabel       matlab.ui.control.Label
        StartYearEditField            matlab.ui.control.NumericEditField
        StartMonthEditFieldLabel      matlab.ui.control.Label
        StartMonthEditField           matlab.ui.control.NumericEditField
        StartDayEditFieldLabel        matlab.ui.control.Label
        StartDayEditField             matlab.ui.control.NumericEditField
        EndYearEditFieldLabel         matlab.ui.control.Label
        EndYearEditField              matlab.ui.control.NumericEditField
        EndMonthEditFieldLabel        matlab.ui.control.Label
        EndMonthEditField             matlab.ui.control.NumericEditField
        EndDayEditFieldLabel          matlab.ui.control.Label
        EndDayEditField               matlab.ui.control.NumericEditField
        LOOKVICLabel                  matlab.ui.control.Label
        SoilparametersLabel           matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        fcanopymultiplierSliderLabel  matlab.ui.control.Label
        fcanopymultiplierSlider       matlab.ui.control.Slider
        LAImultiplierSliderLabel      matlab.ui.control.Label
        LAImultiplierSlider           matlab.ui.control.Slider
        dsSliderLabel                 matlab.ui.control.Label
        dsSlider                      matlab.ui.control.Slider
        t1SliderLabel                 matlab.ui.control.Label
        t1Slider                      matlab.ui.control.Slider
        exptSliderLabel               matlab.ui.control.Label
        exptSlider                    matlab.ui.control.Slider
        wsSliderLabel                 matlab.ui.control.Label
        wsSlider                      matlab.ui.control.Slider
        dsmaxSliderLabel              matlab.ui.control.Label
        dsmaxSlider                   matlab.ui.control.Slider
        ksatSliderLabel               matlab.ui.control.Label
        ksatSlider                    matlab.ui.control.Slider
        bdSliderLabel                 matlab.ui.control.Label
        bdSlider                      matlab.ui.control.Slider
        t2SliderLabel                 matlab.ui.control.Label
        t2Slider                      matlab.ui.control.Slider
        t3SliderLabel                 matlab.ui.control.Label
        t3Slider                      matlab.ui.control.Slider
        wcrSliderLabel                matlab.ui.control.Label
        wcrSlider                     matlab.ui.control.Slider
        bSliderLabel                  matlab.ui.control.Label
        bSlider                       matlab.ui.control.Slider
        wpwpSliderLabel               matlab.ui.control.Label
        wpwpSlider                    matlab.ui.control.Slider
        MakeInputsButton              matlab.ui.control.Button
        ObservationsEditField         matlab.ui.control.EditField
        GlobalParFileButton           matlab.ui.control.Button
        SoilsButton                   matlab.ui.control.Button
        VeglibButton                  matlab.ui.control.Button
        ObservationsButton            matlab.ui.control.Button
        BasinAEditFieldLabel          matlab.ui.control.Label
        BasinAEditField               matlab.ui.control.NumericEditField
        initm1SliderLabel             matlab.ui.control.Label
        initm1Slider                  matlab.ui.control.Slider
        initm2SliderLabel             matlab.ui.control.Label
        initm2Slider                  matlab.ui.control.Slider
        initm3SliderLabel             matlab.ui.control.Label
        initm3Slider                  matlab.ui.control.Slider
        MakeInputsoriginalButton      matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        RunParallelButton             matlab.ui.control.Button
        QbQd_Axes                     matlab.ui.control.UIAxes
        BDCheckBox                    matlab.ui.control.CheckBox
        EXPTCheckBox                  matlab.ui.control.CheckBox
        KSATCheckBox                  matlab.ui.control.CheckBox
        INITM1CheckBox                matlab.ui.control.CheckBox
        INITM2CheckBox                matlab.ui.control.CheckBox
        INITM3CheckBox                matlab.ui.control.CheckBox
        FCANCheckBox                  matlab.ui.control.CheckBox
        LAICheckBox                   matlab.ui.control.CheckBox
        WPWPCheckBox                  matlab.ui.control.CheckBox
        WCRCheckBox                   matlab.ui.control.CheckBox
        T3CheckBox                    matlab.ui.control.CheckBox
        T2CheckBox                    matlab.ui.control.CheckBox
        T1CheckBox                    matlab.ui.control.CheckBox
        WSCheckBox                    matlab.ui.control.CheckBox
        DSMAXCheckBox                 matlab.ui.control.CheckBox
        DSCheckBox                    matlab.ui.control.CheckBox
        BCheckBox                     matlab.ui.control.CheckBox
        TimestepButtonGroup           matlab.ui.container.ButtonGroup
        DailyButton                   matlab.ui.control.RadioButton
        MonthlyButton                 matlab.ui.control.RadioButton
        UnitsButtonGroup              matlab.ui.container.ButtonGroup
        cfsButton                     matlab.ui.control.RadioButton
        cmsButton                     matlab.ui.control.RadioButton
        coresEditFieldLabel           matlab.ui.control.Label
        coresEditField                matlab.ui.control.NumericEditField
        outdirEditField               matlab.ui.control.EditField
        outdirButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        dt; % daily or monthly, timestep of observations
        units;
        nproc = 10;
        prefix = 'wb_';
        
        % Define parameters and set default values
        b;
        ds;
        dsmax;
        ws;
        t1;
        t2;
        t3;
        bd; 
        wcr;
        wpwp;
        ksat;
        expt;
        ffcan;
        fLAI;
        gpf; % global parameter file
        spf; % soil parameter file
        veglib; % vegetation library file
        obs; % observations
        A; % basin area
        kge;

        % outdir = fullfile(pwd, ['vic_out' '/']);
        outdir;
        vic_command = '/home/jschap/Documents/Software/VIC/vic/drivers/classic/vic_classic.exe';
        default_params; % flag for default parameters
        soils;
        soils_orig;
        ncells;
        veg;
        veg_orig;
        global_parfile;
        global_parfile_orig;
        gpf_savename;
        
        veg_savename;
        soil_savename;
        
        initm1;
        initm2;
        initm3;
        
        soil_line;
        veglib_line;
        result_line;
        log_line;
        
        startyear;
        startmonth;
        startday;
        endyear;
        endmonth;
        endday;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Starting LOOKVIC')
            disp(['Working directory is ' pwd])
            addpath('/home/jschap/Documents/Codes/LOOKVIC')
            addpath(genpath('/home/jschap/Documents/Codes/VICMATLAB/vicmatlab'))
            app.default_params = 1;
            if ~exist('vic_out', 'dir')
                mkdir('vic_out')
            end
        end

        % Callback function
        function GlobalparameterfileEditFieldValueChanged(app, event)
            [file,path] = uigetfile('*.txt');
            app.gpf = app.GlobalparameterfileEditField.Value;
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                        
            % Run VIC
            system([app.vic_command ' -g ' app.gpf_savename])
            disp('Running VIC')
            
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            app.b = app.bSlider.Value;
        end

        % Value changed function: dsSlider
        function dsSliderValueChanged(app, event)
            app.ds = app.dsSlider.Value;   
        end

        % Value changed function: dsmaxSlider
        function dsmaxSliderValueChanged(app, event)
            app.dsmax = app.dsmaxSlider.Value;
            
        end

        % Value changed function: wsSlider
        function wsSliderValueChanged(app, event)
            app.ws = app.wsSlider.Value;
            
        end

        % Value changed function: t1Slider
        function t1SliderValueChanged(app, event)
            app.t1 = app.t1Slider.Value;
            
        end

        % Value changed function: t2Slider
        function t2SliderValueChanged(app, event)
            app.t2 = app.t2Slider.Value;
            
        end

        % Value changed function: t3Slider
        function t3SliderValueChanged(app, event)
            app.t3 = app.t3Slider.Value;
            
        end

        % Value changed function: wcrSlider
        function wcrSliderValueChanged(app, event)
            app.wcr = app.wcrSlider.Value;
            
        end

        % Value changed function: wpwpSlider
        function wpwpSliderValueChanged(app, event)
            app.wpwp = app.wpwpSlider.Value;
            
        end

        % Value changed function: bdSlider
        function bdSliderValueChanged(app, event)
            app.bd = app.bdSlider.Value;
            
        end

        % Value changed function: exptSlider
        function exptSliderValueChanged(app, event)
            app.expt = app.exptSlider.Value;
            
        end

        % Value changed function: ksatSlider
        function ksatSliderValueChanged(app, event)
            app.ksat = app.ksatSlider.Value;
            
        end

        % Value changed function: fcanopymultiplierSlider
        function fcanopymultiplierSliderValueChanged(app, event)
            app.ffcan = app.fcanopymultiplierSlider.Value;
            
        end

        % Value changed function: LAImultiplierSlider
        function LAImultiplierSliderValueChanged(app, event)
            app.fLAI = app.LAImultiplierSlider.Value;
            
        end

        % Button pushed function: MakeInputsButton
        function MakeInputsButtonPushed(app, event)
            
            if app.default_params == 1
                app.global_parfile = read_global_param_file(app.gpf);
                app.global_parfile_orig = app.global_parfile;
                app.soils = load(app.spf);
                app.soils_orig = app.soils;
                app.ncells = size(app.soils, 1);
                try
                    app.veg = load(app.veglib); % must be "no-head" veglib
                    app.veg_orig = app.veg;
                catch
                    disp('Make sure the vegetation library has its "head" removed.')
                end
                app.soil_line = find_line(app.gpf, 'SOIL ');
                app.veglib_line = find_line(app.gpf, 'Veg library path/file');
                app.result_line = find_line(app.gpf, 'RESULT_DIR');
                app.log_line = find_line(app.gpf, 'LOG_DIR');
                app.default_params = 0;       
            end
            
            % Update soil and vegetation parameter files
            
            % b
            if app.BCheckBox.Value == 1
                app.soils(:,5) = app.b;
            end
            
            % ds
            if app.DSCheckBox.Value
                app.soils(:,6) = app.ds;
            end
            
            % dsmax
            if app.DSMAXCheckBox.Value
                app.soils(:,7) = app.dsmax;
            end
            
            % ws
            if app.WSCheckBox.Value
                app.soils(:,8) = app.ws;
            end
            
            % t1
            if app.T1CheckBox.Value
                app.soils(:,23) = app.t1;
            end
            
            % t2
            if app.T2CheckBox.Value
                app.soils(:,24) = app.t2;
            end
            
            % t3
            if app.T3CheckBox.Value
                app.soils(:,25) = app.t3;
            end
            
            % ksat
            if app.KSATCheckBox.Value
                app.soils(:,13:15) = app.ksat;
            end
            % bd
            if app.BDCheckBox.Value
                app.soils(:,34:36) = app.bd;
            end
            
            % wcr
            if app.WCRCheckBox.Value
                app.soils(:,41:43) = app.wcr;
            end
            
            % wpwp
            if app.WPWPCheckBox.Value
                app.soils(:,44:46) = app.wpwp;
            end
            
            % expt
            if app.EXPTCheckBox.Value
                app.soils(:,10:12) = app.expt;
            end
            
            % initm1
            if app.INITM1CheckBox.Value
                app.soils(:,19) = app.initm1;
            end
            
            % initm2
            if app.INITM2CheckBox.Value
                app.soils(:,20) = app.initm2;
            end
            
            % initm3
            if app.INITM3CheckBox.Value
                app.soils(:,21) = app.initm3;
            end

            % Checks if any parameters are out of bounds
            if app.soils(:,24) > app.soils(:,25)
                disp('t2 > t3. Setting t2 = t3')
                app.soils(:,24) = app.soils(:,25);
            end
            if app.soils(:,23) > app.soils(:,24)
                disp('t1 > t2. Setting t1 = t2')
                app.soils(:,23) = app.soils(:,24);
            end
            
            % Make sure that wilting point < critical point
            if app.soils(:,44) > app.soils(:,41)
                disp('wpwp > wcr. Setting wpwp < wcr')
                app.soils(:,44) = app.soils(:,41) - 0.001;
            end
              
            % Update soils
%             app.soils(:,5) = app.b;
%             app.soils(:,6) = app.ds;
%             app.soils(:,7) = app.dsmax;
%             app.soils(:,8) = app.ws;
%             app.soils(:,23) = app.t1;
%             app.soils(:,24) = app.t2;
%             app.soils(:,25) = app.t3;
%             app.soils(:,13:15) = app.ksat;
%             app.soils(:,34:36) = app.bd;
%             app.soils(:,44:46) = app.wpwp;
%             app.soils(:,41:43) = app.wcr;
%             app.soils(:,10:12) = app.expt;
%             app.soils(:,19) = app.initm1;
%             app.soils(:,20) = app.initm2;
%             app.soils(:,21) = app.initm3;

            app.soil_savename = './soils_LV.txt';
            app.ncells = size(app.soils, 1);
            write_soils(5, app.soils, app.soil_savename, '3l');
            
            % Update vegetation library
            % fcanopy
            
            if app.LAICheckBox.Value & app.FCANCheckBox.Value
                app.veg(:,5:16) = app.veg_orig(:,5:16)*app.fLAI;
                app.veg(:,17:28) = app.veg_orig(:,17:28)*app.ffcan;            
            end
            
            app.veg_savename = './veglib_LV.txt';
            dlmwrite(app.veg_savename, app.veg, '\t')
            
            % Update global parameter file
            app.gpf_savename = './gpf_LV.txt';
            
            app.global_parfile{13} = ['STARTYEAR ' num2str(app.startyear)];
            app.global_parfile{14} = ['STARTMONTH ' num2str(app.startmonth)];
            app.global_parfile{15} = ['STARTDAY ' num2str(app.startday)];
            
            app.global_parfile{16} = ['ENDYEAR ' num2str(app.endyear)];
            app.global_parfile{17} = ['ENDMONTH ' num2str(app.endmonth)];
            app.global_parfile{18} = ['ENDDAY ' num2str(app.endday)];
            
            app.soil_line = 154; % hard coded for now bc multiple "SOIL" matches in gpf
            app.global_parfile{app.soil_line} = ['SOIL ' app.soil_savename];
            app.global_parfile{app.veglib_line} = ['VEGLIB ' app.veg_savename];
            
            app.global_parfile{app.log_line} = ['LOG_DIR ' fullfile(pwd, ['vic_out' '/'])];
            app.global_parfile{app.result_line} = ['RESULT_DIR ' app.outdir];
            
            write_global_param_file(app.global_parfile, app.gpf_savename)
            
            disp('Inputs are ready. Click "Run" button to run VIC')
            disp('Outputs will be written to the current working directory')
            
        end

        % Button pushed function: GlobalParFileButton
        function GlobalParFileButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd, '*.txt'));
            figure(app.UIFigure)
            app.gpf = fullfile(path, file);
            app.GlobalparameterfileEditField.Value = app.gpf;
        end

        % Button pushed function: SoilsButton
        function SoilsButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd, '*.txt'));
            figure(app.UIFigure)
            app.spf = fullfile(path, file);
            app.SoilparameterfileEditField.Value = app.spf;
        end

        % Button pushed function: ObservationsButton
        function ObservationsButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd, '*.txt'));
            figure(app.UIFigure)
            app.obs = fullfile(path, file);
            app.ObservationsEditField.Value = app.obs;            
        end

        % Button pushed function: VeglibButton
        function VeglibButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd, '*.txt'));
            figure(app.UIFigure)
            app.veglib = fullfile(path, file);
            app.VegetationlibraryEditField.Value = app.veglib;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.soils = app.soils_orig;
            app.veg = app.veg_orig;
            app.global_parfile = app.global_parfile_orig;
            
            dlmwrite(app.veg_savename, app.veg, '\t')
            write_soils(5, app.soils, app.soil_savename, '3l');
            write_global_param_file(app.global_parfile, app.gpf_savename)
            
            cla(app.QbQd_Axes)
            cla(app.Q_Axes)
            cla(app.SM_Axes)
            cla(app.E_Axes)
            
        end

        % Value changed function: EndDayEditField
        function EndDayEditFieldValueChanged(app, event)
            app.endday = app.EndDayEditField.Value;
            disp(['end day is ' num2str(app.endday)])
        end

        % Value changed function: EndMonthEditField
        function EndMonthEditFieldValueChanged(app, event)
            app.endmonth = app.EndMonthEditField.Value;
        end

        % Value changed function: EndYearEditField
        function EndYearEditFieldValueChanged(app, event)
            app.endyear = app.EndYearEditField.Value;
        end

        % Value changed function: StartDayEditField
        function StartDayEditFieldValueChanged(app, event)
            app.startday = app.StartDayEditField.Value;
        end

        % Value changed function: StartMonthEditField
        function StartMonthEditFieldValueChanged(app, event)
            app.startmonth = app.StartMonthEditField.Value;
        end

        % Value changed function: BasinAEditField
        function BasinAEditFieldValueChanged(app, event)
            app.A = app.BasinAEditField.Value;
            
        end

        % Value changed function: StartYearEditField
        function StartYearEditFieldValueChanged(app, event)
            app.startyear = app.StartYearEditField.Value;
            disp(['Start year is ' num2str(app.startyear)])
        end

        % Value changed function: initm1Slider
        function initm1SliderValueChanged(app, event)
            app.initm1 = app.initm1Slider.Value;
        end

        % Value changed function: initm2Slider
        function initm2SliderValueChanged(app, event)
            app.initm2 = app.initm2Slider.Value;
            
        end

        % Value changed function: initm3Slider
        function initm3SliderValueChanged(app, event)
            app.initm3 = app.initm3Slider.Value;
            
        end

        % Button pushed function: MakeInputsoriginalButton
        function MakeInputsoriginalButtonPushed(app, event)
            
            app.veg = load(app.veglib);
            app.veg_savename = './veglib_LV.txt';
            dlmwrite(app.veg_savename, app.veg, '\t')
            
            app.soils = load(app.spf);
            app.soil_savename = './soils_LV.txt';
            write_soils(5, app.soils, app.soil_savename, '3l');
            
            app.gpf_savename = './gpf_LV.txt';
            app.global_parfile = read_global_param_file(app.gpf);
            
            app.global_parfile{13} = ['STARTYEAR ' num2str(app.startyear)];
            app.global_parfile{14} = ['STARTMONTH ' num2str(app.startmonth)];
            app.global_parfile{15} = ['STARTDAY ' num2str(app.startday)];
            
            app.global_parfile{16} = ['ENDYEAR ' num2str(app.endyear)];
            app.global_parfile{17} = ['ENDMONTH ' num2str(app.endmonth)];
            app.global_parfile{18} = ['ENDDAY ' num2str(app.endday)];
            
            app.soil_line = find_line(app.gpf, 'SOIL ');
            app.veglib_line = find_line(app.gpf, 'Veg library path/file');
            app.result_line = find_line(app.gpf, 'RESULT_DIR');
            app.log_line = find_line(app.gpf, 'LOG_DIR');
                
            app.soil_line = 154; % hard coded for now bc multiple "SOIL" matches in gpf
            app.global_parfile{app.soil_line} = ['SOIL ' app.soil_savename];
            app.global_parfile{app.veglib_line} = ['VEGLIB ' app.veg_savename];
            
            app.global_parfile{app.log_line} = ['LOG_DIR ' fullfile(pwd, ['vic_out' '/'])];
            app.global_parfile{app.result_line} = ['RESULT_DIR ' fullfile(pwd, ['vic_out' '/'])];
                        
            write_global_param_file(app.global_parfile, app.gpf_savename)
            
            disp('Inputs are ready. Click "Run" button to run VIC')
            disp('Outputs will be written to the current working directory')

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            app.dt = app.TimestepButtonGroup.SelectedObject.Text;
            app.units = app.UnitsButtonGroup.SelectedObject.Text;
            
             % Load outputs
            disp('Loading VIC outputs')
            % app.outdir = fullfile(pwd, 'vic_out');
            app.prefix = 'wb_'; % hard coded
            [sQ, sQd, sQb, t] = calc_summed_QdQb(app.outdir, app.prefix, app.A); % m^3/s
            t_daily = t;
            
            if strcmp(app.dt, 'Monthly')
                [t, sQ] = daily_to_monthly(t, sQ, 'mean');
            end
            % Load observations (and subset to match simulation)
            [tobs, qobs] = load_streamflow_data(app.obs, 4, lower(app.dt));
            
            if strcmp(app.units, 'cfs')
                qobs = cfs2cms(qobs);
            end
            
            disp('Loading observations for comparison')
            disp('Assuming observations span at least as large a time period as simulation')
            i1 = find(tobs == t(1));
            i2 = find(tobs == t(end));
            tobs = tobs(i1:i2);
            qobs = qobs(i1:i2);
            
            % Plot streamflow
            lw = 1.5;
            plot(tobs, qobs, 'Parent', app.Q_Axes, 'linewidth', lw);
            hold(app.Q_Axes,'on')
            plot(t, sQ, 'Parent', app.Q_Axes, 'linewidth', lw);
            legend(app.Q_Axes, 'Observations','Predictions', 'Location', 'Best')         
            
            % Calculate KGE
            app.kge = myKGE(qobs, sQ);
            
            % Display KGE
            disp(['KGE is ', num2str(app.kge)])
            
            % Plot ET components
%             disp('Loading ET components')
%             [~, ET , ~] = load_vic_output2(app.outdir, 'OUT_EVAP', prefix);
%             [~, T, info] = load_vic_output2(app.outdir, 'OUT_TRANSP_VEG', prefix);
%             [~, BSE, ~] = load_vic_output2(app.outdir, 'OUT_EVAP_BARE', prefix);
%             [~, EC, ~] = load_vic_output2(app.outdir, 'OUT_EVAP_CANOP', prefix);
%             
%             ET = mean(ET, 1);
%             T = mean(T, 1);
%             EC = mean(EC, 1);
%             BSE = mean(BSE, 1);
%             
%             plot(t, ET, 'Parent', app.E_Axes, 'linewidth', lw);
%             hold(app.E_Axes,'on')
%             plot(t, T, 'Parent', app.E_Axes, 'linewidth', lw);
%             plot(t, EC, 'Parent', app.E_Axes, 'linewidth', lw);
%             plot(t, BSE, 'Parent', app.E_Axes, 'linewidth', lw);
%             legend(app.E_Axes, 'ET','T','EC','BSE', 'Location', 'Best')        
            
            % Plot soil moisture components
            disp('Loading soil moisture components')
            app.prefix = 'wb_';
            [~, SM1 , ~] = load_vic_output2(app.outdir, 'OUT_SOIL_MOIST_0', app.prefix);
            [~, SM2, ~] = load_vic_output2(app.outdir, 'OUT_SOIL_MOIST_1', app.prefix);
            [~, SM3, ~] = load_vic_output2(app.outdir, 'OUT_SOIL_MOIST_2', app.prefix);
           
            SM1 = mean(SM1, 1);
            SM2 = mean(SM2, 1);
            SM3 = mean(SM3, 1);
            
            plot(t_daily, SM1, 'Parent', app.SM_Axes, 'linewidth', lw);
            hold(app.SM_Axes,'on')
            plot(t_daily, SM2, 'Parent', app.SM_Axes, 'linewidth', lw);
            plot(t_daily, SM3, 'Parent', app.SM_Axes, 'linewidth', lw);
            legend(app.SM_Axes, 'SM1','SM2','SM3', 'Location', 'Best')   
              
            % Plot runoff and baseflow
            plot(t_daily, sQd, 'Parent', app.QbQd_Axes, 'linewidth', lw);
            hold(app.QbQd_Axes,'on')
            plot(t_daily, sQb, 'Parent', app.QbQd_Axes, 'linewidth', lw);
            legend(app.QbQd_Axes, 'Runoff','Baseflow', 'Location', 'Best')               
            
        end

        % Button pushed function: RunParallelButton
        function RunParallelButtonPushed(app, event)
            app.nproc = app.coresEditField.Value;
            control_params.soil_param = app.soil_savename;
            control_params.global_param_file = app.gpf_savename;
            control_params.out_dir = app.outdir;
            control_params.vic_command = app.vic_command;
            control_params.vic_outdir = app.outdir;
            control_params.log_outdir = app.outdir;
            control_params.vic_indir = app.outdir;
            control_params.n_proc = app.nproc; % number of processors
            control_params.multidir = 0; % flag for using one or multiple directories for VIC outputs
            control_params.soil_format = '3l'; % 3l or livneh            
            control_params.local = 1;
            outname = set_up_parallel(control_params);
            system(outname)
            
            disp(['There are ' num2str(app.ncells) ' grid cells'])
            
            system(['rm ', fullfile(app.outdir, app.prefix), '*'])
            doneval = vic_progressbar(app.ncells, app.outdir, app.prefix);
            
            
        end

        % Button pushed function: outdirButton
        function outdirButtonPushed(app, event)
            [path] = uigetdir(pwd);
            figure(app.UIFigure)
            app.outdir = fullfile(path);
            app.outdirEditField.Value = app.outdir;     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q_Axes = uiaxes(app.UIFigure);
            title(app.Q_Axes, 'Streamflow')
            xlabel(app.Q_Axes, 'Time')
            ylabel(app.Q_Axes, 'Q (m^3/s)')
            app.E_Axes = uiaxes(app.UIFigure);
            title(app.E_Axes, 'Evaporation')
            xlabel(app.E_Axes, 'Time')
            ylabel(app.E_Axes, 'E (mm/day)')
            app.SM_Axes = uiaxes(app.UIFigure);
            title(app.SM_Axes, 'Soil moisture')
            xlabel(app.SM_Axes, 'Time')
            ylabel(app.SM_Axes, 'SM (mm)')
            app.GlobalparameterfileEditField = uieditfield(app.UIFigure, 'text');
            app.SoilparameterfileEditField = uieditfield(app.UIFigure, 'text');
            app.VegetationlibraryEditField = uieditfield(app.UIFigure, 'text');
            app.StartYearEditFieldLabel = uilabel(app.UIFigure);
            app.StartYearEditFieldLabel.Text = 'Start Year';
            app.StartYearEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartYearEditField.ValueChangedFcn = createCallbackFcn(app, @StartYearEditFieldValueChanged, true);
            app.StartMonthEditFieldLabel = uilabel(app.UIFigure);
            app.StartMonthEditFieldLabel.Text = 'Start Month';
            app.StartMonthEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartMonthEditField.ValueChangedFcn = createCallbackFcn(app, @StartMonthEditFieldValueChanged, true);
            app.StartDayEditFieldLabel = uilabel(app.UIFigure);
            app.StartDayEditFieldLabel.Text = 'Start Day';
            app.StartDayEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartDayEditField.ValueChangedFcn = createCallbackFcn(app, @StartDayEditFieldValueChanged, true);
            app.EndYearEditFieldLabel = uilabel(app.UIFigure);
            app.EndYearEditFieldLabel.Text = 'End Year';
            app.EndYearEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndYearEditField.ValueChangedFcn = createCallbackFcn(app, @EndYearEditFieldValueChanged, true);
            app.EndMonthEditFieldLabel = uilabel(app.UIFigure);
            app.EndMonthEditFieldLabel.Text = 'End Month';
            app.EndMonthEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndMonthEditField.ValueChangedFcn = createCallbackFcn(app, @EndMonthEditFieldValueChanged, true);
            app.EndDayEditFieldLabel = uilabel(app.UIFigure);
            app.EndDayEditFieldLabel.Text = 'End Day';
            app.EndDayEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndDayEditField.ValueChangedFcn = createCallbackFcn(app, @EndDayEditFieldValueChanged, true);
            app.LOOKVICLabel = uilabel(app.UIFigure);
            app.LOOKVICLabel.Text = 'LOOKVIC';
            app.SoilparametersLabel = uilabel(app.UIFigure);
            app.SoilparametersLabel.Text = 'Soil parameters';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.fcanopymultiplierSliderLabel = uilabel(app.UIFigure);
            app.fcanopymultiplierSliderLabel.Text = 'fcanopy multiplier';
            app.fcanopymultiplierSlider = uislider(app.UIFigure);
            app.fcanopymultiplierSlider.Limits = [0 2];
            app.fcanopymultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @fcanopymultiplierSliderValueChanged, true);
            app.LAImultiplierSliderLabel = uilabel(app.UIFigure);
            app.LAImultiplierSliderLabel.Text = 'LAI multiplier';
            app.LAImultiplierSlider = uislider(app.UIFigure);
            app.LAImultiplierSlider.Limits = [0 2];
            app.LAImultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @LAImultiplierSliderValueChanged, true);
            app.dsSliderLabel = uilabel(app.UIFigure);
            app.dsSliderLabel.Text = 'ds';
            app.dsSlider = uislider(app.UIFigure);
            app.dsSlider.Limits = [0 1];
            app.dsSlider.ValueChangedFcn = createCallbackFcn(app, @dsSliderValueChanged, true);
            app.t1SliderLabel = uilabel(app.UIFigure);
            app.t1SliderLabel.Text = 't1';
            app.t1Slider = uislider(app.UIFigure);
            app.t1Slider.Limits = [0.05 0.2];
            app.t1Slider.ValueChangedFcn = createCallbackFcn(app, @t1SliderValueChanged, true);
            app.t1Slider.Value = 0.05;
            app.exptSliderLabel = uilabel(app.UIFigure);
            app.exptSliderLabel.Text = 'expt';
            app.exptSlider = uislider(app.UIFigure);
            app.exptSlider.Limits = [3 30];
            app.exptSlider.ValueChangedFcn = createCallbackFcn(app, @exptSliderValueChanged, true);
            app.exptSlider.Value = 3;
            app.wsSliderLabel = uilabel(app.UIFigure);
            app.wsSliderLabel.Text = 'ws';
            app.wsSlider = uislider(app.UIFigure);
            app.wsSlider.Limits = [0 1];
            app.wsSlider.ValueChangedFcn = createCallbackFcn(app, @wsSliderValueChanged, true);
            app.dsmaxSliderLabel = uilabel(app.UIFigure);
            app.dsmaxSliderLabel.Text = 'dsmax';
            app.dsmaxSlider = uislider(app.UIFigure);
            app.dsmaxSlider.Limits = [0 30];
            app.dsmaxSlider.ValueChangedFcn = createCallbackFcn(app, @dsmaxSliderValueChanged, true);
            app.ksatSliderLabel = uilabel(app.UIFigure);
            app.ksatSliderLabel.Text = 'ksat';
            app.ksatSlider = uislider(app.UIFigure);
            app.ksatSlider.Limits = [0 4000];
            app.ksatSlider.ValueChangedFcn = createCallbackFcn(app, @ksatSliderValueChanged, true);
            app.bdSliderLabel = uilabel(app.UIFigure);
            app.bdSliderLabel.Text = 'bd';
            app.bdSlider = uislider(app.UIFigure);
            app.bdSlider.Limits = [1000 1600];
            app.bdSlider.ValueChangedFcn = createCallbackFcn(app, @bdSliderValueChanged, true);
            app.bdSlider.Value = 1300;
            app.t2SliderLabel = uilabel(app.UIFigure);
            app.t2SliderLabel.Text = 't2';
            app.t2Slider = uislider(app.UIFigure);
            app.t2Slider.Limits = [0.1 1.5];
            app.t2Slider.ValueChangedFcn = createCallbackFcn(app, @t2SliderValueChanged, true);
            app.t2Slider.Value = 0.1;
            app.t3SliderLabel = uilabel(app.UIFigure);
            app.t3SliderLabel.Text = 't3';
            app.t3Slider = uislider(app.UIFigure);
            app.t3Slider.Limits = [0.5 2.5];
            app.t3Slider.ValueChangedFcn = createCallbackFcn(app, @t3SliderValueChanged, true);
            app.t3Slider.Value = 0.5;
            app.wcrSliderLabel = uilabel(app.UIFigure);
            app.wcrSliderLabel.Text = 'wcr';
            app.wcrSlider = uislider(app.UIFigure);
            app.wcrSlider.Limits = [0 1];
            app.wcrSlider.ValueChangedFcn = createCallbackFcn(app, @wcrSliderValueChanged, true);
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [0 0.4];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.wpwpSliderLabel = uilabel(app.UIFigure);
            app.wpwpSliderLabel.Text = 'wpwp';
            app.wpwpSlider = uislider(app.UIFigure);
            app.wpwpSlider.Limits = [0 1];
            app.wpwpSlider.ValueChangedFcn = createCallbackFcn(app, @wpwpSliderValueChanged, true);
            app.MakeInputsButton = uibutton(app.UIFigure, 'push');
            app.MakeInputsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeInputsButtonPushed, true);
            app.MakeInputsButton.Text = 'Make Inputs';
            app.ObservationsEditField = uieditfield(app.UIFigure, 'text');
            app.GlobalParFileButton = uibutton(app.UIFigure, 'push');
            app.GlobalParFileButton.ButtonPushedFcn = createCallbackFcn(app, @GlobalParFileButtonPushed, true);
            app.GlobalParFileButton.Text = 'GlobalParFile';
            app.SoilsButton = uibutton(app.UIFigure, 'push');
            app.SoilsButton.ButtonPushedFcn = createCallbackFcn(app, @SoilsButtonPushed, true);
            app.SoilsButton.Text = 'Soils';
            app.VeglibButton = uibutton(app.UIFigure, 'push');
            app.VeglibButton.ButtonPushedFcn = createCallbackFcn(app, @VeglibButtonPushed, true);
            app.VeglibButton.Text = 'Veglib';
            app.ObservationsButton = uibutton(app.UIFigure, 'push');
            app.ObservationsButton.ButtonPushedFcn = createCallbackFcn(app, @ObservationsButtonPushed, true);
            app.ObservationsButton.Text = 'Observations';
            app.BasinAEditFieldLabel = uilabel(app.UIFigure);
            app.BasinAEditFieldLabel.Text = 'Basin A';
            app.BasinAEditField = uieditfield(app.UIFigure, 'numeric');
            app.BasinAEditField.ValueChangedFcn = createCallbackFcn(app, @BasinAEditFieldValueChanged, true);
            app.initm1SliderLabel = uilabel(app.UIFigure);
            app.initm1SliderLabel.Text = 'initm1';
            app.initm1Slider = uislider(app.UIFigure);
            app.initm1Slider.ValueChangedFcn = createCallbackFcn(app, @initm1SliderValueChanged, true);
            app.initm1Slider.Value = 0.5;
            app.initm2SliderLabel = uilabel(app.UIFigure);
            app.initm2SliderLabel.Text = 'initm2';
            app.initm2Slider = uislider(app.UIFigure);
            app.initm2Slider.Limits = [0 200];
            app.initm2Slider.ValueChangedFcn = createCallbackFcn(app, @initm2SliderValueChanged, true);
            app.initm2Slider.Value = 0.5;
            app.initm3SliderLabel = uilabel(app.UIFigure);
            app.initm3SliderLabel.Text = 'initm3';
            app.initm3Slider = uislider(app.UIFigure);
            app.initm3Slider.Limits = [0 800];
            app.initm3Slider.ValueChangedFcn = createCallbackFcn(app, @initm3SliderValueChanged, true);
            app.initm3Slider.Value = 0.5;
            app.MakeInputsoriginalButton = uibutton(app.UIFigure, 'push');
            app.MakeInputsoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @MakeInputsoriginalButtonPushed, true);
            app.MakeInputsoriginalButton.Text = 'Make Inputs (original)';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RunParallelButton = uibutton(app.UIFigure, 'push');
            app.RunParallelButton.ButtonPushedFcn = createCallbackFcn(app, @RunParallelButtonPushed, true);
            app.RunParallelButton.Text = 'Run Parallel';
            app.QbQd_Axes = uiaxes(app.UIFigure);
            title(app.QbQd_Axes, 'Runoff')
            xlabel(app.QbQd_Axes, 'Time')
            ylabel(app.QbQd_Axes, 'Runoff (mm/day)')
            app.BDCheckBox = uicheckbox(app.UIFigure);
            app.BDCheckBox.Text = 'BD';
            app.EXPTCheckBox = uicheckbox(app.UIFigure);
            app.EXPTCheckBox.Text = 'EXPT';
            app.KSATCheckBox = uicheckbox(app.UIFigure);
            app.KSATCheckBox.Text = 'KSAT';
            app.INITM1CheckBox = uicheckbox(app.UIFigure);
            app.INITM1CheckBox.Text = 'INITM1';
            app.INITM2CheckBox = uicheckbox(app.UIFigure);
            app.INITM2CheckBox.Text = 'INITM2';
            app.INITM3CheckBox = uicheckbox(app.UIFigure);
            app.INITM3CheckBox.Text = 'INITM3';
            app.FCANCheckBox = uicheckbox(app.UIFigure);
            app.FCANCheckBox.Text = 'FCAN';
            app.LAICheckBox = uicheckbox(app.UIFigure);
            app.LAICheckBox.Text = 'LAI';
            app.WPWPCheckBox = uicheckbox(app.UIFigure);
            app.WPWPCheckBox.Text = 'WPWP';
            app.WCRCheckBox = uicheckbox(app.UIFigure);
            app.WCRCheckBox.Text = 'WCR';
            app.T3CheckBox = uicheckbox(app.UIFigure);
            app.T3CheckBox.Text = 'T3';
            app.T2CheckBox = uicheckbox(app.UIFigure);
            app.T2CheckBox.Text = 'T2';
            app.T1CheckBox = uicheckbox(app.UIFigure);
            app.T1CheckBox.Text = 'T1';
            app.WSCheckBox = uicheckbox(app.UIFigure);
            app.WSCheckBox.Text = 'WS';
            app.DSMAXCheckBox = uicheckbox(app.UIFigure);
            app.DSMAXCheckBox.Text = 'DSMAX';
            app.DSCheckBox = uicheckbox(app.UIFigure);
            app.DSCheckBox.Text = 'DS';
            app.BCheckBox = uicheckbox(app.UIFigure);
            app.BCheckBox.Text = 'B';
            app.TimestepButtonGroup = uibuttongroup(app.UIFigure);
            app.TimestepButtonGroup.Title = 'Timestep';
            app.DailyButton = uiradiobutton(app.TimestepButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DailyButton.Value = true;
            app.MonthlyButton = uiradiobutton(app.TimestepButtonGroup);
            app.MonthlyButton.Text = 'Monthly';
            app.UnitsButtonGroup = uibuttongroup(app.UIFigure);
            app.UnitsButtonGroup.Title = 'Units';
            app.cfsButton = uiradiobutton(app.UnitsButtonGroup);
            app.cfsButton.Text = 'cfs';
            app.cfsButton.Value = true;
            app.cmsButton = uiradiobutton(app.UnitsButtonGroup);
            app.cmsButton.Text = 'cms';
            app.coresEditFieldLabel = uilabel(app.UIFigure);
            app.coresEditFieldLabel.Text = 'cores';
            app.coresEditField = uieditfield(app.UIFigure, 'numeric');
            app.outdirEditField = uieditfield(app.UIFigure, 'text');
            app.outdirButton = uibutton(app.UIFigure, 'push');
            app.outdirButton.ButtonPushedFcn = createCallbackFcn(app, @outdirButtonPushed, true);
            app.outdirButton.Text = 'outdir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOOKVIC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end